classdef neoPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        ServerTab               matlab.ui.container.Tab
        ConnectButton           matlab.ui.control.Button
        HostEditFieldLabel      matlab.ui.control.Label
        HostEditField           matlab.ui.control.EditField
        PortEditFieldLabel      matlab.ui.control.Label
        PortEditField           matlab.ui.control.EditField
        UsernameEditFieldLabel  matlab.ui.control.Label
        UsernameEditField       matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        PasswordEditField       matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        GraphTab                matlab.ui.container.Tab
        Graph                   matlab.ui.control.UIAxes
        CriteriaListBoxLabel    matlab.ui.control.Label
        CriteriaListBox         matlab.ui.control.ListBox
        NodesTab                matlab.ui.container.Tab
        NodesTable              matlab.ui.control.Table
        PropertiesTable         matlab.ui.control.Table
        LabelDropDownLabel      matlab.ui.control.Label
        LabelDropDown           matlab.ui.control.DropDown
        UITable3                matlab.ui.control.Table
        RelationsTab            matlab.ui.container.Tab
        RelationsDropDownLabel  matlab.ui.control.Label
        RelationsDropDown       matlab.ui.control.DropDown
        UITable                 matlab.ui.control.Table
        UITable2                matlab.ui.control.Table
    end

    
    properties (Access = private)
        NeoConnection % Description
    end
    
    methods (Access = private)


        function results = logger(app, log_message)
            time_str = datestr(datetime('now'));
            app.TextArea.Value{end + 1} = sprintf('[%s] %s\n', time_str, log_message);
        end
    end
    

    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            url = strcat('http://', app.HostEditField.Value, ':', app.PortEditField.Value, '/db/data');
            logger(app, strcat('Connecting to: ', url));
            app.NeoConnection = neo4j(url, app.UsernameEditField.Value, app.PasswordEditField.Value);
            logger(app, strcat('Server response: ', app.NeoConnection.Message));
            logger(app, 'Fetching DB Labels');
            labels = nodeLabels(app.NeoConnection)
            app.LabelDropDown.Items = labels;
            app.LabelDropDown.Enable = 'on';
            LabelDropDownValueChanged(app, event);
            logger(app, 'Fetching DB Relations');
            relations = relationTypes(app.NeoConnection)
            app.RelationsDropDown.Items = relations;
            app.RelationsDropDown.Enable = 'on';
            combined = [labels; relations]
            app.CriteriaListBox.Items = combined
        end

        % Value changed function: LabelDropDown
        function LabelDropDownValueChanged(app, event)
            value = app.LabelDropDown.Value;
            logger(app, sprintf('Populate Nodes Table with: %s', value));
            table_data = {};
            db_data = searchNode(app.NeoConnection, value);
            objects = [db_data.NodeObject];
            for i = 1:numel(objects)
                object = objects(i);
                table_data{end+1, 1} = object.NodeID;
                table_data{end, 2} = object.NodeData.name;
                table_data{end, 3} = object.NodeLabels;
            end
            app.NodesTable.Data = table_data;
        end

        % Cell selection callback: NodesTable
        function NodesTableCellSelection(app, event)
            indices = event.Indices;
            row = indices(1);
            id = app.NodesTable.Data{row, 1};
            node = searchNodeByID(app.NeoConnection, id);
            node_data = node.NodeData;
            fields = fieldnames(node_data);
            table_data = cell(numel(fields), 2);
            for i = 1:numel(fields)
                fieldname = fields{i};
                fieldval = node_data.(fieldname)
                table_data{i, 1} = fieldname;
                table_data{i, 2} = fieldval;
            end
            app.PropertiesTable.Data = table_data;
        end

        % Value changed function: RelationsDropDown
        function RelationsDropDownValueChanged(app, event)
            value = app.RelationsDropDown.Value
        end

        % Value changed function: CriteriaListBox
        function CriteriaListBoxValueChanged(app, event)
            value = app.CriteriaListBox.Value
            graph_data = searchGraph(app.NeoConnection, {value})
            rownames = graph_data.Nodes.Properties.RowNames;
            nodedata = [graph_data.Nodes.NodeData{rownames}]
            nodenames = {nodedata(:).name}
            
            graph = neo4jStruct2Digraph(graph_data, 'NodeNames', nodenames)
            plot(app.Graph, graph, 'EdgeLabel', graph.Edges.RelationType)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ServerTab = uitab(app.TabGroup);
            app.ServerTab.Title = 'Server';
            app.ConnectButton = uibutton(app.ServerTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.HostEditFieldLabel = uilabel(app.ServerTab);
            app.HostEditFieldLabel.Text = 'Host';
            app.HostEditField = uieditfield(app.ServerTab, 'text');
            app.HostEditField.Tooltip = {'Name of Neo4J host server.'};
            app.HostEditField.Value = 'localhost';
            app.PortEditFieldLabel = uilabel(app.ServerTab);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.ServerTab, 'text');
            app.PortEditField.Value = '7474';
            app.UsernameEditFieldLabel = uilabel(app.ServerTab);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.ServerTab, 'text');
            app.UsernameEditField.Value = 'neo4j';
            app.PasswordEditFieldLabel = uilabel(app.ServerTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.ServerTab, 'text');
            app.PasswordEditField.Value = 'neo';
            app.Label = uilabel(app.ServerTab);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.ServerTab);
            app.TextArea.Value = {'Logs...'};
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.Graph = uiaxes(app.GraphTab);
            app.Graph.XTick = [];
            app.Graph.XTickLabel = '';
            app.Graph.YTick = [];
            app.Graph.YTickLabel = '';
            app.CriteriaListBoxLabel = uilabel(app.GraphTab);
            app.CriteriaListBoxLabel.Text = 'Criteria';
            app.CriteriaListBox = uilistbox(app.GraphTab);
            app.CriteriaListBox.Items = {};
            app.CriteriaListBox.ValueChangedFcn = createCallbackFcn(app, @CriteriaListBoxValueChanged, true);
            app.CriteriaListBox.Value = {};
            app.NodesTab = uitab(app.TabGroup);
            app.NodesTab.Title = 'Nodes';
            app.NodesTable = uitable(app.NodesTab);
            app.NodesTable.ColumnName = {'ID'; 'Name'; 'Labels'};
            app.NodesTable.RowName = {};
            app.NodesTable.CellSelectionCallback = createCallbackFcn(app, @NodesTableCellSelection, true);
            app.NodesTable.Tooltip = {'List of all nodes fetched from graph database.'};
            app.PropertiesTable = uitable(app.NodesTab);
            app.PropertiesTable.ColumnName = {'Property'; 'Value'};
            app.PropertiesTable.RowName = {};
            app.PropertiesTable.Tooltip = {'Detailed information about selected node.'};
            app.LabelDropDownLabel = uilabel(app.NodesTab);
            app.LabelDropDown = uidropdown(app.NodesTab);
            app.LabelDropDown.Items = {};
            app.LabelDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelDropDownValueChanged, true);
            app.LabelDropDown.Enable = 'off';
            app.LabelDropDown.Value = {};
            app.UITable3 = uitable(app.NodesTab);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.RelationsTab = uitab(app.TabGroup);
            app.RelationsTab.Title = 'Relations';
            app.RelationsDropDownLabel = uilabel(app.RelationsTab);
            app.RelationsDropDownLabel.Text = 'Relations';
            app.RelationsDropDown = uidropdown(app.RelationsTab);
            app.RelationsDropDown.Items = {};
            app.RelationsDropDown.ValueChangedFcn = createCallbackFcn(app, @RelationsDropDownValueChanged, true);
            app.RelationsDropDown.Enable = 'off';
            app.RelationsDropDown.Value = {};
            app.UITable = uitable(app.RelationsTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.RelationsTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = neoPlotter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end